classdef SOFC_fault_diagnose_1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        Panel_2                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        lambdaButton            matlab.ui.control.Button
        load_net                matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        Panel                   matlab.ui.container.Panel
        monitor                 matlab.ui.control.StateButton
        open_uart               matlab.ui.control.StateButton
        select_botrate          matlab.ui.control.DropDown
        DropDown_2Label         matlab.ui.control.Label
        flag_led                matlab.ui.control.Lamp
        select_uart             matlab.ui.control.DropDown
        Label                   matlab.ui.control.Label
        search_uart             matlab.ui.control.StateButton
        RightPanel              matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        GridLayout5             matlab.ui.container.GridLayout
        EditField_13            matlab.ui.control.EditField
        EditField_beijing_time  matlab.ui.control.EditField
        beijing_time            matlab.ui.control.Label
        fault_time              matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        sEditField              matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        monitor_flag            matlab.ui.control.Label
        heat_exchanger_fault    matlab.ui.control.Label
        blower_fault            matlab.ui.control.Label
        stack_leakage           matlab.ui.control.Label
        normal                  matlab.ui.control.Label
        clear                   matlab.ui.control.Button
        stop_plot               matlab.ui.control.StateButton
        Tab_2                   matlab.ui.container.Tab
        GridLayout4             matlab.ui.container.GridLayout
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        TextArea_2              matlab.ui.control.TextArea
        other                   matlab.ui.control.TextArea
        classify_state          matlab.ui.control.TextArea
        Tab_3                   matlab.ui.container.Tab
        GridLayout6             matlab.ui.container.GridLayout
        Am2Label_2              matlab.ui.control.Label
        Am2Label                matlab.ui.control.Label
        molsLabel_3             matlab.ui.control.Label
        atmLabel_3              matlab.ui.control.Label
        molsLabel_2             matlab.ui.control.Label
        atmLabel_2              matlab.ui.control.Label
        molsLabel               matlab.ui.control.Label
        atmLabel                matlab.ui.control.Label
        WLabel                  matlab.ui.control.Label
        VLabel                  matlab.ui.control.Label
        ALabel                  matlab.ui.control.Label
        EditField_11            matlab.ui.control.EditField
        EditField_10            matlab.ui.control.EditField
        EditField_9             matlab.ui.control.EditField
        EditField_8             matlab.ui.control.EditField
        EditField_7             matlab.ui.control.EditField
        EditField_6             matlab.ui.control.EditField
        EditField_5             matlab.ui.control.EditField
        EditField_4             matlab.ui.control.EditField
        EditField_3             matlab.ui.control.EditField
        EditField_2             matlab.ui.control.EditField
        EditField               matlab.ui.control.EditField
        EditField_Ic            matlab.ui.control.EditField
        EditField_Ia            matlab.ui.control.EditField
        EditField_molH2O        matlab.ui.control.EditField
        EditField_PH2O          matlab.ui.control.EditField
        EditField_molO2         matlab.ui.control.EditField
        EditField_PO2           matlab.ui.control.EditField
        EditField_molH2         matlab.ui.control.EditField
        EditField_PH2           matlab.ui.control.EditField
        EditField_P             matlab.ui.control.EditField
        EditField_V             matlab.ui.control.EditField
        EditField_I             matlab.ui.control.EditField
        SOFCLabel               matlab.ui.control.Label
        PH2o_mol                matlab.ui.control.UIAxes
        PO2_mol                 matlab.ui.control.UIAxes
        output_v                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
        PH2_mol                 matlab.ui.control.UIAxes
        I_c                     matlab.ui.control.UIAxes
        I_a                     matlab.ui.control.UIAxes
        PH2o                    matlab.ui.control.UIAxes
        PO2                     matlab.ui.control.UIAxes
        PH2                     matlab.ui.control.UIAxes
        output_p                matlab.ui.control.UIAxes
        output_i                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
         %% Description
         COMS;
         port;
         port_use;           %串口号
         botrate;            %波特率设置
         s;                  %串口结构体数据
         rec_dat;            %单行数据获取
         count_time;         %时间计数
         count_iter=0;       %迭代
         split_dat1;         %数据划分
         split_dat2;
         split_dat3;
         split_dat4;
         split_dat5;
         split_dat6;
         split_dat7;
         split_dat8;
         split_dat9;
         split_dat10;
         split_dat11;
         temporary=0;       %暂停标志
         flag_monitor;      %监测标志
         flag_load_net;     %装载网络标志
         flag_load_lambda;  %装载PCA特征向量标志
         lambda;            %特征向量
         net;               %网络结构
         train_data;        %参数标准化（训练集数据输入进行标准化）
         standard_data;     %PCA降维标准化，给定任意200个样本作为标准化的基础
         label;             %预测标签
         flag_fault;        %故障判断
         flag_false=0;      %标志
         currentDateTime;   %当前时间
         time;              %定时器
         
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 初始化回调函数
            [app.COMS,app.port] = get_com_ports();
            app.select_uart.Items=app.COMS;%读取设备的串口数量
            app.net=load("GWO_GRU_NET97.16.mat");%默认装载GWO-GRU网络
            app.net=app.net.net;
            app.lambda=load("lambda.mat");%默认装载lambda
            app.lambda=app.lambda.V;
            app.train_data=load("train_data.mat");%装载训练样本
            app.train_data=app.train_data.data_finish;
            app.standard_data=load("standard_data.mat");%装载PCA标准化基础样本
            app.standard_data=app.standard_data.standard_data;
            % 实时显示北京时间
            app.time = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~) updateTimeHandler(app));
            start(app.time);
            
            app.select_botrate.Value='9600';
            app. botrate=9600;%初始化给定默认值9600；
        end

        % Value changed function: select_uart
        function select_uartValueChanged(app, event)
            %% 串口号选择回调函数
            value = app.select_uart.Value;
            for i=1:length(app.select_uart.Items)
                if strcmp(value,app.select_uart.Items{i})
                   app.port_use=char(app.port(i));%读取使用的串口号
                end
            end
%             disp(app.port_use)

        end

        % Value changed function: search_uart
        function search_uartValueChanged(app, event)
            % 串口号搜索函数
            value = app.search_uart.Value;
            if value==1
               app.search_uart.Text='停止搜索';
            else
               app.search_uart.Text='搜索串口';
            end
            [app.COMS,app.port] = get_com_ports();
            app.select_uart.Items=app.COMS;%读取设备的串口数量
            
        end

        % Value changed function: open_uart
        function open_uartValueChanged(app, event)
            %% 串口打开操作
            value = app.open_uart.Value;
            if value==1
                app.flag_led.Color='g';
                app.open_uart.Text='关闭串口';
                app.s=serialport(app.port_use,app. botrate,"Timeout",10);%打开串口

            else%关闭开关
                app.flag_led.Color='r';
                app.open_uart.Text='打开串口';
                fclose(app.s);
                delete(app.s);
            end

        end

        % Value changed function: select_botrate
        function select_botrateValueChanged(app, event)
            %% 波特率的获取
            value = app.select_botrate.Value;
            app. botrate=str2double(value);%获取得到botrate值
%             disp(app. botrate)

        end

        % Value changed function: stop_plot
        function stop_plotValueChanged(app, event)
            value = app.stop_plot.Value;
            app.temporary=value;
            if app.temporary==1%图像暂停按键控制
               app.stop_plot.Text='继续';
               app.stop_plot.BackgroundColor='r';
            else
               app.stop_plot.Text='暂停';
               app.stop_plot.BackgroundColor='g';
            end
            
%             disp(value)
        end

        % Value changed function: monitor
        function monitorValueChanged(app, event)
            value = app.monitor.Value;
            app.flag_monitor=value;
            for i=0:1:inf
                if app.flag_monitor==1
                app.monitor.Text='结束监测';
                app.rec_dat=fgetl(app.s);
                app.rec_dat=app.rec_dat(1:(length(app.rec_dat)-1));%去除最后的回车符
                app.rec_dat=split(app.rec_dat,' ');%划分数据
                if i~=0&&app.temporary==0&&length(app.rec_dat)==11%% 分别划分给各自数据
                    app.count_iter=app.count_iter+1;%时间叠加
                    app.count_time(app.count_iter)=app.count_iter;
                    app.split_dat1(app.count_iter)=str2double(app.rec_dat(1,1));%输出电流
                    app.split_dat2(app.count_iter)=str2double(app.rec_dat(2,1));%输出电压
                    app.split_dat3(app.count_iter)=str2double(app.rec_dat(3,1));%输出功率
                    app.split_dat4(app.count_iter)=str2double(app.rec_dat(4,1));%氢气分压
                    app.split_dat5(app.count_iter)=str2double(app.rec_dat(5,1));%氢气输出摩尔流量
                    app.split_dat6(app.count_iter)=str2double(app.rec_dat(6,1));%氧气分压
                    app.split_dat7(app.count_iter)=str2double(app.rec_dat(7,1));%氧气输出摩尔流量
                    app.split_dat8(app.count_iter)=str2double(app.rec_dat(8,1));%水蒸气分压
                    app.split_dat9(app.count_iter)=str2double(app.rec_dat(9,1));%水蒸气输出摩尔流量
                    app.split_dat10(app.count_iter)=str2double(app.rec_dat(10,1));%阳极交换电流密度
                    app.split_dat11(app.count_iter)=str2double(app.rec_dat(11,1));%阴极交换电流密度
                    data=[ app.split_dat1(app.count_iter)  app.split_dat2(app.count_iter)  app.split_dat3(app.count_iter)... 
                    app.split_dat4(app.count_iter) app.split_dat5(app.count_iter)  app.split_dat6(app.count_iter)... 
                    app.split_dat7(app.count_iter) app.split_dat8(app.count_iter)  app.split_dat9(app.count_iter)...
                    app.split_dat10(app.count_iter)  app.split_dat11(app.count_iter)];%合并数据
                    % 赋值数据显示 转换成字符串
                    app.EditField_I.Value=string(app.split_dat1(app.count_iter));%电流数据
                    app.EditField_V.Value=string(app.split_dat2(app.count_iter));%电压数据
                    app.EditField_P.Value=string(app.split_dat3(app.count_iter));%功率数据
                    app.EditField_PH2.Value=string(app.split_dat4(app.count_iter));%氢气分压数据
                    app.EditField_molH2.Value=string(app.split_dat5(app.count_iter));%氢气摩尔流量数据
                    app.EditField_PO2.Value=string(app.split_dat6(app.count_iter));%氧气分压数据
                    app.EditField_molO2.Value=string(app.split_dat7(app.count_iter));%氧气摩尔流量数据
                    app.EditField_PH2O.Value=string(app.split_dat8(app.count_iter));%水蒸气分压数据
                    app.EditField_molH2O.Value=string(app.split_dat9(app.count_iter));%水蒸气摩尔流量数据
                    app.EditField_Ia.Value=string(app.split_dat10(app.count_iter));%阳极交换电流密度数据
                    app.EditField_Ic.Value=string(app.split_dat11(app.count_iter));%阴极交换电流密度数据
                    app.sEditField.Value=app.count_iter;%采集时间
                    
                    app.label(app.count_iter)=PCA_dimension(app.net,app.lambda,data,app.train_data,app.standard_data);%获取数据集预测标签                                   
                    app.flag_fault(1,app.count_iter)=app.label(app.count_iter);%故障标签转换
                    %% 读取故障时间
                    if length(app.flag_fault)>=3
                    if app.flag_fault(1,length(app.flag_fault)-2)==2&&app.flag_fault(1,length(app.flag_fault)-1)==2 ...
                       &&app.flag_fault(1,length(app.flag_fault))==2&&app.flag_false==0
                        app.fault_time.Value=app.count_iter-2;
                        app.flag_false=1;
                        t=datetime('now', 'TimeZone', 'Asia/Shanghai');%故障的北京时间获取
                        app.EditField_beijing_time.Value=string(t);
                    elseif app.flag_fault(1,length(app.flag_fault)-2)==3&&app.flag_fault(1,length(app.flag_fault)-1)==3 ...
                       &&app.flag_fault(1,length(app.flag_fault))==3&&app.flag_false==0
                        app.fault_time.Value=app.count_iter-2;
                        app.flag_false=1;
                        t=datetime('now', 'TimeZone', 'Asia/Shanghai');
                        app.EditField_beijing_time.Value=string(t);
                    elseif app.flag_fault(1,length(app.flag_fault)-2)==4&&app.flag_fault(1,length(app.flag_fault)-1)==4 ...
                       &&app.flag_fault(1,length(app.flag_fault))==4&&app.flag_false==0
                        app.fault_time.Value=app.count_iter-2;
                        app.flag_false=1;
                        t=datetime('now', 'TimeZone', 'Asia/Shanghai');
                        app.EditField_beijing_time.Value=string(t);
                    elseif app.flag_fault(1,length(app.flag_fault)-2)==1&&app.flag_fault(1,length(app.flag_fault)-1)==1 ...
                       &&app.flag_fault(1,length(app.flag_fault))==1
                        app.flag_false=0; 
                    end
                    end

                    %% 监测运行标识
                    if app.label(app.count_iter)==1
                       app.normal.BackgroundColor=[0.00,1.00,0.00];
                       app.stack_leakage.BackgroundColor=[0.80,0.80,0.80];
                       app.blower_fault.BackgroundColor=[0.80,0.80,0.80];
                       app.heat_exchanger_fault.BackgroundColor=[0.80,0.80,0.80];
                    elseif app.label(app.count_iter)==2
                       app.normal.BackgroundColor=[0.80,0.80,0.80];
                       app.stack_leakage.BackgroundColor=[1.00,0.00,0.00];
                       app.blower_fault.BackgroundColor=[0.80,0.80,0.80];
                       app.heat_exchanger_fault.BackgroundColor=[0.80,0.80,0.80];
                    elseif app.label(app.count_iter)==3
                       app.normal.BackgroundColor=[0.80,0.80,0.80];
                       app.stack_leakage.BackgroundColor=[0.80,0.80,0.80];
                       app.blower_fault.BackgroundColor=[1.00,0.00,0.00];
                       app.heat_exchanger_fault.BackgroundColor=[0.80,0.80,0.80];
                    else
                       app.normal.BackgroundColor=[0.80,0.80,0.80];
                       app.stack_leakage.BackgroundColor=[0.80,0.80,0.80];
                       app.blower_fault.BackgroundColor=[0.80,0.80,0.80];
                       app.heat_exchanger_fault.BackgroundColor=[1.00,0.00,0.00];
                    end
                    
%                     disp(app.label)
%                     disp(data)
%                     disp(app.rec_dat)
%                     disp(app.count_time)
%% 输出电流
                    plot(app.output_i,app.count_time,app.split_dat1(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.output_i,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat1);
                        if y_up~=y_down
                        ylim(app.output_i,[y_down y_up]);
                        end
                    else
                        xlim(app.output_i,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat1);
                        if y_up~=y_down
                        ylim(app.output_i,[y_down y_up]);
                        end
                    end
%% 输出电压
                    plot(app.output_v,app.count_time,app.split_dat2(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.output_v,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat2);
                        if y_up~=y_down
                        ylim(app.output_v,[y_down y_up]);
                        end
                    else
                        xlim(app.output_v,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat2);
                        if y_up~=y_down
                        ylim(app.output_v,[y_down y_up]);
                        end
                    end
%% 输出功率
                    plot(app.output_p,app.count_time,app.split_dat3(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.output_p,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat3);
                        if y_up~=y_down
                        ylim(app.output_p,[y_down y_up]);
                        end
                    else
                        xlim(app.output_p,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat3);
                        if y_up~=y_down
                        ylim(app.output_p,[y_down y_up]);
                        end
                    end
%% 氢气分压
                    plot(app.PH2,app.count_time,app.split_dat4(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.PH2,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat4);
                        if y_up~=y_down
                        ylim(app.PH2,[y_down y_up]);
                        end
                    else
                        xlim(app.PH2,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat4);
                        if y_up~=y_down
                        ylim(app.PH2,[y_down y_up]);
                        end
                    end
%% 氢气输出摩尔流量
                    plot(app.PH2_mol,app.count_time,app.split_dat5(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.PH2_mol,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat5);
                        if y_up~=y_down
                        ylim(app.PH2_mol,[y_down y_up]);
                        end
                    else
                        xlim(app.PH2_mol,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat5);
                        if y_up~=y_down
                        ylim(app.PH2_mol,[y_down y_up]);
                        end
                    end
%% 氧气分压
                    plot(app.PO2,app.count_time,app.split_dat6(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.PO2,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat6);
                        if y_up~=y_down
                        ylim(app.PO2,[y_down y_up]);
                        end
                    else
                        xlim(app.PO2,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat6);
                        if y_up~=y_down
                        ylim(app.PO2,[y_down y_up]);
                        end
                    end      
%% 氧气输出摩尔流量
                    plot(app.PO2_mol,app.count_time,app.split_dat7(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.PO2_mol,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat7);
                        if y_up~=y_down
                        ylim(app.PO2_mol,[y_down y_up]);
                        end
                    else
                        xlim(app.PO2_mol,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat7);
                        if y_up~=y_down
                        ylim(app.PO2_mol,[y_down y_up]);
                        end
                    end
%% 水蒸气分压
                    plot(app.PH2o,app.count_time,app.split_dat8(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.PH2o,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat8);
                        if y_up~=y_down
                        ylim(app.PH2o,[y_down y_up]);
                        end
                    else
                        xlim(app.PH2o,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat8);
                        if y_up~=y_down
                        ylim(app.PH2o,[y_down y_up]);
                        end
                    end
%% 水蒸气输出摩尔流量
                    plot(app.PH2o_mol,app.count_time,app.split_dat9(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.PH2o_mol,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat9);
                        if y_up~=y_down
                        ylim(app.PH2o_mol,[y_down y_up]);
                        end
                    else
                        xlim(app.PH2o_mol,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat9);
                        if y_up~=y_down
                        ylim(app.PH2o_mol,[y_down y_up]);
                        end
                    end
%% 阳极交换电流密度
                    plot(app.I_a,app.count_time,app.split_dat10(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.I_a,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat10);
                        if y_up~=y_down
                        ylim(app.I_a,[y_down y_up]);
                        end
                    else
                        xlim(app.I_a,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat10);
                        if y_up~=y_down
                        ylim(app.I_a,[y_down y_up]);
                        end
                    end
%% 阴极交换电流密度
                    plot(app.I_c,app.count_time,app.split_dat11(app.count_time),'LineWidth',0.5,'Color','r');
                    if length(app.count_time)<=70
                        xlim(app.I_c,[0 100]);
                        [y_down,y_up]=adjust_ylim(app.split_dat11);
                        if y_up~=y_down
                        ylim(app.I_c,[y_down y_up]);
                        end
                    else
                        xlim(app.I_c,[length(app.count_time)-70 length(app.count_time)+30]);
                        [y_down,y_up]=adjust_ylim(app.split_dat11);
                        if y_up~=y_down
                        ylim(app.I_c,[y_down y_up]);
                        end
                    end
%% 实时预测图像
                    plot(app.UIAxes,app.count_time,app.label(app.count_time),Marker="*",Color="b",LineStyle="-")
                    ylim(app.UIAxes,[1 4]);
                    if length(app.count_time)<=140
                        xlim(app.UIAxes,[0 200]);
                    else
                        xlim(app.UIAxes,[length(app.count_time)-140 length(app.count_time)+60]);
                    end

%                     currentDateTime = datetime('now', 'TimeZone', 'Asia/Shanghai');
%                     app.beijing_time.Text=string(currentDateTime);
%                     disp(currentDateTime)
%% 其他                   
                    pause(1);%0.5s有问题，未解决
                end
                elseif app.flag_monitor==0
                    app.stop_plot.Value=0;%使暂停按键恢复初始状态
                    app.temporary=0;%使暂停按键恢复初始状态
                    app.stop_plot.BackgroundColor='g';
                    app.stop_plot.Text='暂停';
                    app.monitor.Text='开始监测';
                    break;%跳出循环
                end
            end
        end

        % Button pushed function: load_net
        function load_netButtonPushed(app, event)
            % 导入net函数回调
            [a,b]=uigetfile;%直接叉掉窗口返回值为0
               if all(a~=0)&&all(b~=0)
                  path=sprintf("%s\\%s",b,a);
                  loadnet=load(path);
                  app.net=loadnet;%把导入的load_net赋值给net
                  app.net=app.net.net;
               else
                  app.net=load("GWO_GRU_NET97.05.mat");
                  app.net=app.net.net;
               end
        end

        % Button pushed function: lambdaButton
        function lambdaButtonPushed(app, event)
            % 导入lambda函数回调
            [a,b]=uigetfile;%直接叉掉窗口返回值为0
               if all(a~=0)&&all(b~=0)
                  path=sprintf("%s\\%s",b,a);
                  loadnet=load(path);
                  app.lambda=loadnet;%把导入的load_lambda赋值给lambda
                  app.lambda=app.lambda.V;%
               else
                  app.lambda=load("lambda.mat");
                  app.lambda=app.lambda.V;
               end
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            % 数据初始化
            app.count_iter=0;
            app.rec_dat=0;            %单行数据获取
            app.count_time=0;         %时间计数
            app.count_iter=0;         %迭代
            app.split_dat1=0;         %数据划分
            app.split_dat2=0;
            app.split_dat3=0;
            app.split_dat4=0;
            app.split_dat5=0;
            app.split_dat6=0;
            app.split_dat7=0;
            app.split_dat8=0;
            app.split_dat9=0;
            app.split_dat10=0;
            app.split_dat11=0;
            app.label=0;%预测标签
            app.flag_fault=0;
            app.EditField_beijing_time.Value=' ';
            app.fault_time.Value=0;
            app.sEditField.Value=0;
            
            cla(app.output_i);%清除图窗
            cla(app.output_p);
            cla(app.PH2);
            cla(app.PO2);
            cla(app.PH2o);
            cla(app.I_a);
            cla(app.I_c);
            cla(app.PH2_mol);
            cla(app.output_v);
            cla(app.UIAxes);
            cla(app.PO2_mol);
            cla(app.PH2o_mol);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 软件关闭回调
            if isvalid(app.time) && isa(app.time, 'timer') % 检查定时器是否存在
               stop(app.time);    % 停止定时器
               delete(app.time);  % 删除对象
            end
            if isa(app.s, 'serialport') % 检查串口是否打开
               if isvalid(app.s)%初始化时app.s为空
                  fclose(app.s);%关闭串口
                  delete(app.s);%删除串口
               end
            end
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '参数选择';
            app.search_uart = uibutton(app.Panel, 'state');
            app.search_uart.ValueChangedFcn = createCallbackFcn(app, @search_uartValueChanged, true);
            app.search_uart.Text = '搜索串口';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '串口选择';
            app.select_uart = uidropdown(app.Panel);
            app.select_uart.Items = {'none'};
            app.select_uart.ValueChangedFcn = createCallbackFcn(app, @select_uartValueChanged, true);
            app.select_uart.Value = 'none';
            app.flag_led = uilamp(app.Panel);
            app.DropDown_2Label = uilabel(app.Panel);
            app.DropDown_2Label.Text = '波特率';
            app.select_botrate = uidropdown(app.Panel);
            app.select_botrate.Items = {'110', '300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '38400', '57600', '115200', '128000', '256000'};
            app.select_botrate.ValueChangedFcn = createCallbackFcn(app, @select_botrateValueChanged, true);
            app.select_botrate.Value = '110';
            app.open_uart = uibutton(app.Panel, 'state');
            app.open_uart.ValueChangedFcn = createCallbackFcn(app, @open_uartValueChanged, true);
            app.open_uart.Text = '打开串口';
            app.monitor = uibutton(app.Panel, 'state');
            app.monitor.ValueChangedFcn = createCallbackFcn(app, @monitorValueChanged, true);
            app.monitor.Text = '开始监测';
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '其他功能';
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.TextArea = uitextarea(app.GridLayout3);
            app.TextArea.Value = {'默认网络为GWO-GRU网络，PCA特征向量为默认，降维到4个主成分。'; '可以自主加载其他网络结果，以及降维所具有的lambda特征向量'; '加载的net，内部变量名必须为net'; '加载的lambda，内部变量名必须为V'};
            app.load_net = uibutton(app.GridLayout3, 'push');
            app.load_net.ButtonPushedFcn = createCallbackFcn(app, @load_netButtonPushed, true);
            app.load_net.Text = '加载net';
            app.lambdaButton = uibutton(app.GridLayout3, 'push');
            app.lambdaButton.ButtonPushedFcn = createCallbackFcn(app, @lambdaButtonPushed, true);
            app.lambdaButton.Text = '加载lambda';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.output_i = uiaxes(app.GridLayout2);
            title(app.output_i, '输出电流')
            xlabel(app.output_i, '时间（t）')
            ylabel(app.output_i, '电流（A）')
            zlabel(app.output_i, 'Z')
            app.output_p = uiaxes(app.GridLayout2);
            title(app.output_p, '输出功率')
            xlabel(app.output_p, '时间（t）')
            ylabel(app.output_p, '功率（W）')
            zlabel(app.output_p, 'Z')
            app.PH2 = uiaxes(app.GridLayout2);
            title(app.PH2, '氢气分压')
            xlabel(app.PH2, '时间（t）')
            ylabel(app.PH2, '分压（atm）')
            zlabel(app.PH2, 'Z')
            app.PO2 = uiaxes(app.GridLayout2);
            title(app.PO2, '氧气分压')
            xlabel(app.PO2, '时间（t）')
            ylabel(app.PO2, '分压（atm）')
            zlabel(app.PO2, 'Z')
            app.PH2o = uiaxes(app.GridLayout2);
            title(app.PH2o, '水蒸气分压')
            xlabel(app.PH2o, '时间（t）')
            ylabel(app.PH2o, '分压（atm）')
            zlabel(app.PH2o, 'Z')
            app.I_a = uiaxes(app.GridLayout2);
            title(app.I_a, '阳极交换电流密度')
            xlabel(app.I_a, '时间（t）')
            ylabel(app.I_a, '电流密度（A/m^2）')
            zlabel(app.I_a, 'Z')
            app.I_c = uiaxes(app.GridLayout2);
            title(app.I_c, '阴极交换电流密度')
            xlabel(app.I_c, '时间（t）')
            ylabel(app.I_c, '电流密度（A/m^2）')
            zlabel(app.I_c, 'Z')
            app.PH2_mol = uiaxes(app.GridLayout2);
            title(app.PH2_mol, '氢气摩尔流量')
            xlabel(app.PH2_mol, '时间（t）')
            ylabel(app.PH2_mol, '摩尔流量（mol/s）')
            zlabel(app.PH2_mol, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '实时诊断')
            xlabel(app.UIAxes, '时间（t）')
            ylabel(app.UIAxes, '类别标签')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [1 4];
            app.output_v = uiaxes(app.GridLayout2);
            title(app.output_v, '输出电压')
            xlabel(app.output_v, '时间（t）')
            ylabel(app.output_v, '电压（V）')
            zlabel(app.output_v, 'Z')
            app.PO2_mol = uiaxes(app.GridLayout2);
            title(app.PO2_mol, '氧气摩尔流量')
            xlabel(app.PO2_mol, '时间（t）')
            ylabel(app.PO2_mol, '摩尔流量（mol/s）')
            zlabel(app.PO2_mol, 'Z')
            app.PH2o_mol = uiaxes(app.GridLayout2);
            title(app.PH2o_mol, '水蒸气摩尔流量')
            xlabel(app.PH2o_mol, '时间（t）')
            ylabel(app.PH2o_mol, '摩尔流量（mol/s）')
            zlabel(app.PH2o_mol, 'Z')
            app.SOFCLabel = uilabel(app.GridLayout2);
            app.SOFCLabel.Text = 'SOFC参数检测及故障监测';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '参数监控';
            app.GridLayout5 = uigridlayout(app.Tab);
            app.stop_plot = uibutton(app.GridLayout5, 'state');
            app.stop_plot.ValueChangedFcn = createCallbackFcn(app, @stop_plotValueChanged, true);
            app.stop_plot.Text = '暂停';
            app.clear = uibutton(app.GridLayout5, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = '清除';
            app.normal = uilabel(app.GridLayout5);
            app.normal.Text = '正常运行';
            app.stack_leakage = uilabel(app.GridLayout5);
            app.stack_leakage.Text = '电堆泄露';
            app.blower_fault = uilabel(app.GridLayout5);
            app.blower_fault.Text = '鼓风机故障';
            app.heat_exchanger_fault = uilabel(app.GridLayout5);
            app.heat_exchanger_fault.Text = '热交换器故障';
            app.monitor_flag = uilabel(app.GridLayout5);
            app.monitor_flag.Text = '监测标志';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Text = '采集时间(s)';
            app.sEditField = uieditfield(app.GridLayout5, 'numeric');
            app.Label_5 = uilabel(app.GridLayout5);
            app.Label_5.Text = '故障时间(s)';
            app.fault_time = uieditfield(app.GridLayout5, 'numeric');
            app.beijing_time = uilabel(app.GridLayout5);
            app.beijing_time.Text = '0-0-0 00:00:00';
            app.EditField_beijing_time = uieditfield(app.GridLayout5, 'text');
            app.EditField_13 = uieditfield(app.GridLayout5, 'text');
            app.EditField_13.Value = '故障北京时间';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '说明';
            app.GridLayout4 = uigridlayout(app.Tab_2);
            app.classify_state = uitextarea(app.GridLayout4);
            app.classify_state.Value = {'类别1：正常数据样本'; '类别2：电堆泄露故障样本'; '类别3：鼓风机故障样本'; '类别4：热交换器故障样本'};
            app.other = uitextarea(app.GridLayout4);
            app.other.Value = {'1、使用时建议先结束监测后在关闭串口，如果不关闭串口，监测按键可作为图像的暂停按键，与暂停按键功能一样'; '2、清除图窗前建议先暂停运行或者关闭监测'; '3、如果运行状态暂停，此时结束监测按键初始化，开始监测会接着进行监测'};
            app.TextArea_2 = uitextarea(app.GridLayout4);
            app.TextArea_2.Value = {'1、采集时间为1s采集一次，无法在外部更改，只能在程序内部修改，可以自己引出'; '2、连续出现三次故障即读取第一次故障出现的时间'};
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '使用说明';
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '其他说明';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '实时参数值';
            app.GridLayout6 = uigridlayout(app.Tab_3);
            app.EditField_I = uieditfield(app.GridLayout6, 'text');
            app.EditField_V = uieditfield(app.GridLayout6, 'text');
            app.EditField_P = uieditfield(app.GridLayout6, 'text');
            app.EditField_PH2 = uieditfield(app.GridLayout6, 'text');
            app.EditField_molH2 = uieditfield(app.GridLayout6, 'text');
            app.EditField_PO2 = uieditfield(app.GridLayout6, 'text');
            app.EditField_molO2 = uieditfield(app.GridLayout6, 'text');
            app.EditField_PH2O = uieditfield(app.GridLayout6, 'text');
            app.EditField_molH2O = uieditfield(app.GridLayout6, 'text');
            app.EditField_Ia = uieditfield(app.GridLayout6, 'text');
            app.EditField_Ic = uieditfield(app.GridLayout6, 'text');
            app.EditField = uieditfield(app.GridLayout6, 'text');
            app.EditField.Value = '输出电流';
            app.EditField_2 = uieditfield(app.GridLayout6, 'text');
            app.EditField_2.Value = '输出电压';
            app.EditField_3 = uieditfield(app.GridLayout6, 'text');
            app.EditField_3.Value = '输出功率';
            app.EditField_4 = uieditfield(app.GridLayout6, 'text');
            app.EditField_4.Value = '氢气分压';
            app.EditField_5 = uieditfield(app.GridLayout6, 'text');
            app.EditField_5.Value = '氢气摩尔流量';
            app.EditField_6 = uieditfield(app.GridLayout6, 'text');
            app.EditField_6.Value = '氧气分压';
            app.EditField_7 = uieditfield(app.GridLayout6, 'text');
            app.EditField_7.Value = '氧气摩尔流量';
            app.EditField_8 = uieditfield(app.GridLayout6, 'text');
            app.EditField_8.Value = '水蒸气分压';
            app.EditField_9 = uieditfield(app.GridLayout6, 'text');
            app.EditField_9.Value = '水蒸气摩尔流量';
            app.EditField_10 = uieditfield(app.GridLayout6, 'text');
            app.EditField_10.Value = '阳极交换电流密度';
            app.EditField_11 = uieditfield(app.GridLayout6, 'text');
            app.EditField_11.Value = '阴极交换电流密度';
            app.ALabel = uilabel(app.GridLayout6);
            app.ALabel.Text = '(A)';
            app.VLabel = uilabel(app.GridLayout6);
            app.VLabel.Text = '(V)';
            app.WLabel = uilabel(app.GridLayout6);
            app.WLabel.Text = '(W)';
            app.atmLabel = uilabel(app.GridLayout6);
            app.atmLabel.Text = '(atm)';
            app.molsLabel = uilabel(app.GridLayout6);
            app.molsLabel.Text = '(mol/s)';
            app.atmLabel_2 = uilabel(app.GridLayout6);
            app.atmLabel_2.Text = '(atm)';
            app.molsLabel_2 = uilabel(app.GridLayout6);
            app.molsLabel_2.Text = '(mol/s)';
            app.atmLabel_3 = uilabel(app.GridLayout6);
            app.atmLabel_3.Text = '(atm)';
            app.molsLabel_3 = uilabel(app.GridLayout6);
            app.molsLabel_3.Text = '(mol/s)';
            app.Am2Label = uilabel(app.GridLayout6);
            app.Am2Label.Text = '(A/m^2)';
            app.Am2Label_2 = uilabel(app.GridLayout6);
            app.Am2Label_2.Text = '(A/m^2)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SOFC_fault_diagnose_1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end